classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        MaxyEditFieldLabel  matlab.ui.control.Label
        MaxyEditField       matlab.ui.control.NumericEditField
        MaxxEditFieldLabel  matlab.ui.control.Label
        MaxxEditField       matlab.ui.control.NumericEditField
        UITable             matlab.ui.control.Table
        ExportPathButton    matlab.ui.control.Button
        FileEditFieldLabel  matlab.ui.control.Label
        FileEditField       matlab.ui.control.EditField
        LoadButton          matlab.ui.control.Button
        AddnewshrinkButton  matlab.ui.control.Button
        ClearButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        fileName="" % Description
    end
    
    properties (Access = public)
        pathList % Description
    end
    
    methods (Access = public)
        function results = updatePlot(app)
            app.UIAxes.cla;
            for i=1:size(app.UITable.Data,1)
               line(app.UIAxes,[app.UITable.Data(i,1), app.UITable.Data(i,3)],[app.UITable.Data(i,2),app.UITable.Data(i,4)]) 
            end
        end
        
        function results = updateFileField(app)
            app.FileEditField.Value=app.fileName;
        end
        
        function results = getFileName(app)
            results = app.fileName;
        end
        
        function results = setFileName(app, fn)
            app.fileName=fn;
            updateFileField(app);
        end
        
        function results = resetAllData(app)
            app.UITable.Data=zeros(1,numel(app.UITable.ColumnName));
        end
        
        function results = sortPath(app)
            tempArray=[];
            for i=1:size(app.UITable.Data,1)
                tempArray(i)=0.5*(app.UITable.Data(i,1)+app.UITable.Data(i,3));
            end
            [sortedVal,index]=sort(tempArray);
            for i=1:size(app.UITable.Data,1)
                results(i,:)=app.UITable.Data(index(i),:);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, loadfile)
            app.UITable.Data=zeros(1,numel(app.UITable.ColumnName));
            value = app.MaxxEditField.Value;
            app.UIAxes.XLim=[0 value];
            value = app.MaxyEditField.Value;
            app.UIAxes.YLim=[0 value];
        end

        % Value changed function: MaxxEditField
        function MaxxEditFieldValueChanged(app, event)
            value = app.MaxxEditField.Value;
            app.UIAxes.XLim=[0 value];
        end

        % Value changed function: MaxyEditField
        function MaxyEditFieldValueChanged(app, event)
            value = app.MaxyEditField.Value;
            app.UIAxes.YLim=[0 value];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            updatePlot(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path]=uigetfile('*.mat');
            drawnow;
            figure(app.UIFigure);
            if (file~=0)
                app.setFileName(fullfile(path,file));
                load(app.getFileName);
                app.UITable.Data(1:size(X1,1),1)=X1;
                app.UITable.Data(1:size(Y2,1),2)=Y1;
                app.UITable.Data(1:size(X2,1),3)=X2;
                app.UITable.Data(1:size(Y2,1),4)=Y2;
                app.UITable.Data(1:size(V,1),5)=V;
                updatePlot(app);
            end
        end

        % Button pushed function: ExportPathButton
        function ExportPathButtonPushed(app, event)
            exportData=sortPath(app)
            X1=exportData(:,1);
            Y1=exportData(:,2);
            X2=exportData(:,3);
            Y2=exportData(:,4);
            V=exportData(:,5);
            [file,path]=uiputfile('*.mat');
            app.setFileName(fullfile(path,file));
            save(app.getFileName,'X1','Y1','X2','Y2','V');
            drawnow;
            figure(app.UIFigure);
            app.setFileName(fullfile(path,file));
        end

        % Callback function
        function CreateNewButtonPushed(app, event)
            app.CreateNewButton.Enable='off';
            app.createFileDialog=createfile(app,app.fileName);
        end

        % Button pushed function: AddnewshrinkButton
        function AddnewshrinkButtonPushed(app, event)
            app.UITable.Data=[app.UITable.Data;0 0 0 0 0]
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data=[0 0 0 0 0]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MaxyEditFieldLabel = uilabel(app.UIFigure);
            app.MaxyEditFieldLabel.Text = 'Max y';
            app.MaxyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxyEditField.ValueChangedFcn = createCallbackFcn(app, @MaxyEditFieldValueChanged, true);
            app.MaxyEditField.Value = 800;
            app.MaxxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxxEditFieldLabel.Text = 'Max x';
            app.MaxxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxxEditFieldValueChanged, true);
            app.MaxxEditField.Value = 1600;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start point X'; 'Start point Y'; 'End point X'; 'End point Y'; 'Velocity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ExportPathButton = uibutton(app.UIFigure, 'push');
            app.ExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPathButtonPushed, true);
            app.ExportPathButton.Text = 'Export Path';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.FileEditField.Editable = 'off';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddnewshrinkButton = uibutton(app.UIFigure, 'push');
            app.AddnewshrinkButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewshrinkButtonPushed, true);
            app.AddnewshrinkButton.Text = {'Add new shrink'; ''};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end